classdef save4Dimages < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectimagestosaveListBoxLabel  matlab.ui.control.Label
        save_list                       matlab.ui.control.ListBox
        SaveImagesButton                matlab.ui.control.Button
        FilenamePrefixLabel             matlab.ui.control.Label
        prefix_field                    matlab.ui.control.EditField
        saveall_box                     matlab.ui.control.CheckBox
        CloseButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        image_list
        current_list
        previous_list
        path
        CallingApp
    end

    methods (Access = private)
    
        function saveImages(app)
            % Save as scaled uint16 Tiff images
            
            % add in an underscore (_) between the prefix and the preset filenames
            if ~isempty(app.prefix_field.Value)
                app.prefix_field.Value=strcat(app.prefix_field.Value,'_');
            end

            if sum(ismember(app.current_list,'PACBED.tif'))
                imwrite(uint16(rescale(app.CallingApp.img,0,2^16-1)),strcat(app.prefix_field.Value,'PACBED.tif')); end
            if sum(ismember(app.current_list,'adf.tif'))
                imwrite(uint16(rescale(app.CallingApp.adf,0,2^16-1)),strcat(app.prefix_field.Value,'adf.tif')); end
            if sum(ismember(app.current_list,'dpc_x.tif'))
                imwrite(uint16(rescale(app.CallingApp.images.ac,0,2^16-1)),strcat(app.prefix_field.Value,'dpc_x.tif')); end
            if sum(ismember(app.current_list,'dpc_y.tif'))
                imwrite(uint16(rescale(app.CallingApp.images.bd,0,2^16-1)),strcat(app.prefix_field.Value,'dpc_y.tif')); end 
            
            if sum(ismember(app.current_list,'detector_mask.tif'))
                if app.CallingApp.SegmentedButton.Value==1
                    det='A':'Z';
                    total_detector_mask=zeros(size(app.CallingApp.nopix,app.CallingApp.nopiy));
                    for j=1:app.CallingApp.nrung.Value
                        for i=1:app.CallingApp.nseg.Value
                            if app.CallingApp.nrung.Value>1
                                temp_name=sprintf('%s%d',det(i),j);
                            else
                                temp_name=det(i);
                            end
                            imwrite(uint16(rescale(app.CallingApp.mask.(temp_name),0,2^16-1)),strcat(app.prefix_field.Value,'detector_mask_',temp_name,'.tif'));
                            total_detector_mask=total_detector_mask+rescale(app.CallingApp.mask.(temp_name),0,2^16-1);
                        end
                    end
                    imwrite(uint16(rescale(total_detector_mask,0,2^16-1)),strcat(app.prefix_field.Value,'detector_mask_total.tif'));
                else
                    imwrite(uint16(rescale(app.CallingApp.mask.bfdf,0,2^16-1)),strcat(app.prefix_field.Value,'detector_mask.tif'));
                end
            end
            
            if sum(ismember(app.current_list,'virtual_ap.tif'))
                imwrite(uint16(rescale(app.CallingApp.mask.bfdf,0,2^16-1)),strcat(app.prefix_field.Value,'virtual_ap.tif')); end 
            
            if sum(ismember(app.current_list,'vectMag.tif'))
                imwrite(uint16(rescale(app.CallingApp.dpc_mag,0,2^16-1)),strcat(app.prefix_field.Value,'vect_mag.tif')); end 

            if sum(ismember(app.current_list,'vectAngle.tif'))
                imwrite(uint8(app.CallingApp.dpc_angle/(2*pi)*2^8),hsv(256),strcat(app.prefix_field.Value,'vectAngle.tif')); end
            
            if sum(ismember(app.current_list,'colorWheel.tif'))
                imwrite(uint8(app.CallingApp.colorwheel_image/(2*pi)*2^8),hsv(256),strcat(app.prefix_field.Value,'colorWheel.tif')); end
            
            if sum(ismember(app.current_list,'vector.tif'))
                dpc_angle_norm=uint8(app.CallingApp.dpc_angle/(2*pi)*2^8);
                temp_colormap=hsv(2^8);
                for i=1:size(temp_colormap,1)
                    dpc_angle_rgb(dpc_angle_norm==i,1)=temp_colormap(i,1);
                    dpc_angle_rgb(dpc_angle_norm==i,2)=temp_colormap(i,2);
                    dpc_angle_rgb(dpc_angle_norm==i,3)=temp_colormap(i,3);
                end
                dpc_angle_rgb=reshape(dpc_angle_rgb,[app.CallingApp.framesx,app.CallingApp.framesy,3]);
                imwrite(dpc_angle_rgb.*app.CallingApp.dpc_mag_norm,strcat(app.prefix_field.Value,'vector.tif'));
            end
            
            if sum(ismember(app.current_list,'colorWheel.tif'))
                colorwheel_norm=uint8(app.CallingApp.colorwheel_image/(2*pi)*(2^8-1))+1;
                [xgrid, ygrid] = meshgrid(1:app.CallingApp.nopix, 1:app.CallingApp.nopix);
                mask=((xgrid-app.CallingApp.center(1)).^2 + (ygrid-app.CallingApp.center(2)).^2) <= (app.CallingApp.nopix/2)^2;
                temp_colormap=hsv(2^8);
                for i=1:size(temp_colormap,1)
                    colorwheel_rgb(colorwheel_norm==i,1)=temp_colormap(i,1);
                    colorwheel_rgb(colorwheel_norm==i,2)=temp_colormap(i,2);
                    colorwheel_rgb(colorwheel_norm==i,3)=temp_colormap(i,3);
                end
                colorwheel_rgb=reshape(colorwheel_rgb,[app.CallingApp.nopix,app.CallingApp.nopix,3]);
                imwrite(colorwheel_rgb.*mask,strcat(app.prefix_field.Value,'colorWheel.tif'));
            end

            % remove underscore (_) between the prefix and the preset filenames
            if ~isempty(strfind(app.prefix_field.Value,'_'))
                app.prefix_field.Value=app.prefix_field.Value(1:end-1);
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appMain)
            app.CallingApp=appMain;
            if app.CallingApp.SegmentedButton.Value==1
                app.image_list={'PACBED.tif';
                                'adf.tif';
                                'dpc_x.tif';
                                'dpc_y.tif';
                                'vectMag.tif';
                                'vectAngle.tif';
                                'vector.tif';
                                'colorWheel.tif';
                                'detector_mask.tif'};
            elseif app.CallingApp.AnnularRoundButton.Value==1
                app.image_list={'PACBED.tif';
                                'adf.tif';
                                'detector_mask.tif'};
            elseif app.CallingApp.VirtualApertureButton.Value==1
                app.image_list={'PACBED.tif';
                                'adf.tif';
                                'virtual_ap.tif'};
            elseif app.CallingApp.CenterofMassButton.Value==1
                app.image_list={'PACBED.tif';
                                'adf.tif';
                                'com_x.tif';
                                'com_y.tif';
                                'vectMag.tif';
                                'vectAngle.tif';
                                'vector.tif';
                                'colorWheel.tif'};
            end
            app.save_list.Items=app.image_list;
            app.prefix_field.Value='';
            app.save_list.Value=app.save_list.Items(1);
            app.previous_list=app.image_list(1);
        end

        % Callback function: prefix_field, prefix_field
        function prefix_Callback(app, event)
            if isempty(event.Value)
                app.save_list.Items=strcat(app.image_list);
            else
                app.save_list.Items=strcat(event.Value,'_',app.image_list);
            end
            app.previous_list=app.image_list(ismember(app.save_list.Items,app.save_list.Value));
        end

        % Value changed function: saveall_box
        function save_all_callback(app, event)
            if isempty(app.previous_list)
                app.previous_list=app.image_list(1); end
            
            if app.saveall_box.Value==1
                app.save_list.Value=app.save_list.Items;
             elseif ~isempty(app.prefix_field.Value)
                 app.save_list.Value=strcat(app.prefix_field.Value,'_',app.previous_list);
            else
                app.save_list.Value=app.previous_list;
            end
            app.current_list=app.image_list(ismember(app.save_list.Items,app.save_list.Value));
        end

        % Value changed function: save_list
        function selection_Callback(app, event)
            app.previous_list=app.image_list(ismember(app.save_list.Items,app.save_list.Value));
            if isempty(app.previous_list)
                app.previous_list=app.image_list(1); end
            app.current_list=app.image_list(ismember(app.save_list.Items,app.save_list.Value));
            if sum(sum(ismember(app.save_list.Items,app.save_list.Value)))==size(app.save_list.Items,2)
                app.saveall_box.Value=1;
            else
                app.saveall_box.Value=0;
            end            
        end

        % Button pushed function: SaveImagesButton
        function save_Callback(app, event)
            app.path=uigetdir;
            saveImages(app);
        end

        % Button pushed function: CloseButton
        function closePushed(app, event)
            closereq
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectimagestosaveListBoxLabel = uilabel(app.UIFigure);
            app.SelectimagestosaveListBoxLabel.Text = 'Select images to save:';
            app.save_list = uilistbox(app.UIFigure);
            app.save_list.Items = {};
            app.save_list.Multiselect = 'on';
            app.save_list.ValueChangedFcn = createCallbackFcn(app, @selection_Callback, true);
            app.save_list.Value = {};
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save_Callback, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.FilenamePrefixLabel = uilabel(app.UIFigure);
            app.FilenamePrefixLabel.Text = 'Filename Prefix:';
            app.prefix_field = uieditfield(app.UIFigure, 'text');
            app.prefix_field.ValueChangedFcn = createCallbackFcn(app, @prefix_Callback, true);
            app.prefix_field.ValueChangingFcn = createCallbackFcn(app, @prefix_Callback, true);
            app.saveall_box = uicheckbox(app.UIFigure);
            app.saveall_box.ValueChangedFcn = createCallbackFcn(app, @save_all_callback, true);
            app.saveall_box.Text = 'Save all';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closePushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = save4Dimages(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end